classdef Test5B < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MotorStopButton   matlab.ui.control.Button
        MotorStartButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CompPort = "COM4"
        BaudRate = 115200
        SerialObj
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Initialization

            app.CompPort = "COM4";
            app.BaudRate = 115200;

            %try-catch

            try
                app.SerialObj = serialport(app.CompPort, app.BaudRate);
                configureTerminator(app.SerialObj, "LF"); 
                disp("Serial Port initialized correctly");
            catch
                disp("Serial Port did not initialize correctly");
            end
            
        end

        % Button pushed function: MotorStartButton
        function StartButtonPushed(app, event)
            
            try
                if isempty(app.SerialObj) || ~isvalid(app.SerialObj)
                    app.SerialObj = serialport(app.CompPort, app.BaudRate);
                end
                
                writeline(app.SerialObj, "1");
                disp('Sent 1 to Serial to start the motor');
            catch ME
                uialert(app.UIFigure, ME.message, 'Serial Connection Error');
            end
        end

        % Button pushed function: MotorStopButton
        function StopButtonPushed(app, event)
            try
                if isempty(app.SerialObj) || ~isvalid(app.SerialObj)
                    app.SerialOb = serialport(app.CompPort, app.BaudRate);
                end

                %Comminucation with ESP32
                % 1 Starts the Motor
                % 0 Shuts the motor off

                writeline(app.SerialObj, "0");
                disp('Sent 0 to Serial to start the motor');

            catch ME
                uialert(app.UIFigure, ME.message, 'Serial COnnection Error')

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MotorStartButton = uibutton(app.UIFigure, 'push');
            app.MotorStartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.MotorStartButton.Text = 'Motor Start';
            app.MotorStopButton = uibutton(app.UIFigure, 'push');
            app.MotorStopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.MotorStopButton.Text = 'Motor Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Test5B

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end