classdef Test_6B < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SerialPortEditField        matlab.ui.control.EditField
        SerialPortEditFieldLabel   matlab.ui.control.Label
        DownloadDataButton         matlab.ui.control.Button
        StopDataCollectionButton   matlab.ui.control.Button
        HumidityGauge              matlab.ui.control.Gauge
        HumidityGaugeLabel         matlab.ui.control.Label
        StartDataCollectionButton  matlab.ui.control.Button
        Axes_Thermistor            matlab.ui.control.UIAxes
        Axes_DHT                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SerialObj
        TimerObj
        HistoryTable
        Count = 0
        % Description
    end
    
    methods (Access = private)
        
        function results = func(app)
            if ~isempty(app.SerialObj) && app.SerialObj.NumBytesAvailable > 0
    dataLine = readline(app.SerialObj);
    parsedData = sscanf(dataLine, '%f,%f,%f'); % Read floats: Humidity, DHT_Temp, Thermistor_Temp

    if length(parsedData) == 3
        app.Count = app.Count + 1;

        % Append values to history tracking array
        newRow = table(app.Count, parsedData(1), parsedData(2), parsedData(3), ...
            'VariableNames', {'Sample', 'Humidity', 'DHT_Temp', 'Therm_Temp'});
        app.HistoryTable = [app.HistoryTable; newRow];

        % Update the Analog Gauge Display
        app.HumidityGauge.Value = parsedData(1);

        % Determine plot visibility limits (Keep a rolling window of last 50 points)
        startIdx = max(1, app.Count - 50);
        displayData = app.HistoryTable(startIdx:end, :);

        % Update Graph 1: DHT22 Temperature
        plot(app.Axes_DHT, displayData.Sample, displayData.DHT_Temp, '-r', 'LineWidth', 1.5);
        grid(app.Axes_DHT, 'on');

        % Update Graph 2: Thermistor Temperature Probe
        plot(app.Axes_Thermistor, displayData.Sample, displayData.Therm_Temp, '-b', 'LineWidth', 1.5);
        grid(app.Axes_Thermistor, 'on');

        % Force MATLAB to render UI queues immediately
        drawnow limitrate;
    end
end 
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            varNames = {'Sample', 'Humidity', 'DHT_Temp', 'Therm_Temp'};
            varTypes = {'double', 'double', 'double', 'double'};
            app.HistoryTable = table('Size', [0,4], 'VariableTypes', varTypes, 'VariableNames', varNames);

            %Visual Grid Stuff
            grid(app.Axes_DHT, 'on');
            grid(app.Axes_Thermistor, "on");
        end

        % Button pushed function: StartDataCollectionButton
        function ShowData(app, event)
            port = app.SerialPortEditField.Value;

            try
                app.SerialObj = serialport(port, 115200);
                configureTerminator(app.SerialObj, "LF");
                flush(app.SerialObj);

                app.TimerObj = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, 'TimerFcn', @(~,~) app.func());
                start(app.TimerObj);
            catch ME
                uialert(app.UIFigure, ['Port COnnection Failed" ' ME.message], 'Connection Error');
            end

        end

        % Button pushed function: DownloadDataButton
        function DownloadData(app, event)
            % Ensure data exists prior to running file generation
            if isempty(app.HistoryTable)
                 uialert(app.UIFigure, 'No data collected yet', 'Warning');
                    return;
            end

            [file, path] = uiputfile('*.csv', 'Export as CSV', 'SensorData.csv');

            if ischar(file)
                fullFilePath = fullfile(path, file);
             try
                 writetable(app.HistoryTable, fullFilePath);
                  uialert(app.UIFigure, ['File exported to: ' file], 'Success');
              catch ME
                 uialert(app.UIFigure, ['Failed to save file: ' ME.message], 'Export Error');
    end
end
        end

        % Button pushed function: StopDataCollectionButton
        function StopData(app, event)
            if ~isempty(app.TimerObj) && isvalid(app.TimerObj)
                stop(app.TimerObj);
                delete(app.TimerObj);
            end
            if ~isempty(app.SerialObj) && isvalid(app.SerialObj)
                delete(app.SerialObj);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.TimerObj) && isvalid(app.TimerObj)
                stop(app.TimerObj);
                delete(app.TimerObj);
            end
                if ~isempty(app.SerialObj) && isvalid(app.SerialObj)
                    delete(app.SerialObj);
                end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Axes_DHT = uiaxes(app.UIFigure);
            title(app.Axes_DHT, 'DHT 22 Temp')
            xlabel(app.Axes_DHT, 'X')
            ylabel(app.Axes_DHT, 'Y')
            zlabel(app.Axes_DHT, 'Z')
            app.Axes_Thermistor = uiaxes(app.UIFigure);
            title(app.Axes_Thermistor, 'Thermistor Temp')
            xlabel(app.Axes_Thermistor, 'X')
            ylabel(app.Axes_Thermistor, 'Y')
            zlabel(app.Axes_Thermistor, 'Z')
            app.StartDataCollectionButton = uibutton(app.UIFigure, 'push');
            app.StartDataCollectionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowData, true);
            app.StartDataCollectionButton.Text = 'Start Data Collection';
            app.HumidityGaugeLabel = uilabel(app.UIFigure);
            app.HumidityGaugeLabel.Text = 'Humidity Gauge';
            app.HumidityGauge = uigauge(app.UIFigure, 'circular');
            app.StopDataCollectionButton = uibutton(app.UIFigure, 'push');
            app.StopDataCollectionButton.ButtonPushedFcn = createCallbackFcn(app, @StopData, true);
            app.StopDataCollectionButton.Text = 'Stop Data Collection';
            app.DownloadDataButton = uibutton(app.UIFigure, 'push');
            app.DownloadDataButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadData, true);
            app.DownloadDataButton.Text = 'Download Data';
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.UIFigure, 'text');
            app.SerialPortEditField.Value = 'COM4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Test_6B

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end